classdef adapt_aep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TempButton                     matlab.ui.control.Button
        HealthButton                   matlab.ui.control.Button
        DropDown_sex                   matlab.ui.control.DropDown
        PauseButton                    matlab.ui.control.Button
        Label_time_elapsed             matlab.ui.control.Label
        TotaltimeelapsedLabel          matlab.ui.control.Label
        peramplitudeLabel              matlab.ui.control.Label
        Label_grand_total              matlab.ui.control.Label
        Label_rejection_rate           matlab.ui.control.Label
        GrandnumberoftrialspresentedLabel  matlab.ui.control.Label
        Label_number_trials_presented  matlab.ui.control.Label
        Label_current_amp              matlab.ui.control.Label
        TrialrejectionrateLabel        matlab.ui.control.Label
        NumberoftrialspresentedLabel   matlab.ui.control.Label
        CurrentamplitudeLabel          matlab.ui.control.Label
        DropDown_tone_burst            matlab.ui.control.DropDown
        StatusLabel                    matlab.ui.control.Label
        InitializeButton               matlab.ui.control.Button
        AnalysisLabel                  matlab.ui.control.Label
        MonitorLabel                   matlab.ui.control.Label
        DropDown_response_feature      matlab.ui.control.DropDown
        StartButton                    matlab.ui.control.Button
        CalibrateButton                matlab.ui.control.Button
        logo                           matlab.ui.control.Image
        EditField_max_trials           matlab.ui.control.NumericEditField
        EditField_frequency            matlab.ui.control.NumericEditField
        EditField_ID                   matlab.ui.control.NumericEditField
        ExperimentLabel                matlab.ui.control.Label
        MaximumtrialsLabel_2           matlab.ui.control.Label
        ResponsefeatureLabel           matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        StimulusLabel                  matlab.ui.control.Label
        SexLabel                       matlab.ui.control.Label
        IDLabel                        matlab.ui.control.Label
        SubjectLabel                   matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        UIAxes_monte                   matlab.ui.control.UIAxes
        UIAxes_slope_est               matlab.ui.control.UIAxes
        UIAxes_thresh_est              matlab.ui.control.UIAxes
        UIAxes_diff_fft                matlab.ui.control.UIAxes
        UIAxes_temp                    matlab.ui.control.UIAxes
        UIAxes_health                  matlab.ui.control.UIAxes
        UIAxes_model                   matlab.ui.control.UIAxes
        UIAxes_boot                    matlab.ui.control.UIAxes
        UIAxes_ch4                     matlab.ui.control.UIAxes
        UIAxes_ch3                     matlab.ui.control.UIAxes
        UIAxes_ch2                     matlab.ui.control.UIAxes
        UIAxes_ch1                     matlab.ui.control.UIAxes
        UIAxes_hydrophone              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ex % Experiment structure
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function start_exp(app, event)
            addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))

            app.CalibrateButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';

            app.ex.info.animal.filename_root = strcat(app.ex.info.animal.species_name, '_', app.ex.info.animal.subject_ID, '_', string(app.ex.stimulus.frequency_hz));
            % Rip it
            run_adapt_aep(app);
        end

        % Button pushed function: CalibrateButton
        function start_calibration(app, event)
            addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))

            % Check if ex has been initialized
            if isempty(app.ex) || ~isfield(app.ex, 'info')
                uialert(app.UIFigure, 'Please initialize experiment first.', 'Not Initialized');
                return;
            end

            % Create dialog asking to load existing or create new calibration
            choice = uiconfirm(app.UIFigure, ...
                'Do you want to load an existing calibration file or create a new one?', ...
                'Calibration Options', ...
                'Options', {'Load Existing', 'Create New'}, ...
                'DefaultOption', 1);

            if strcmp(choice, 'Load Existing')
                % Allow user to select calibration file
                [file, path] = uigetfile('*.mat', 'Select Calibration File');

                if file == 0
                    % User cancelled
                    return;
                end

                try
                    % Load calibration data
                    loaded = load(fullfile(path, file), 'calibration_data');
                    fprintf('Loaded calibration data from: %s\n', file);
                    app.ex.calibration = loaded.calibration_data;
                catch ME
                    uialert(app.UIFigure, sprintf('Error loading calibration: %s', ME.message), 'Load Error');
                end

            else
                try
                    % Create the calibration app
                    calibration_app = calibrate_stimulus();

                    % Initialize it with ex
                    calibration_app.initializeWithEx(app.ex);

                    % Wait for calibration to complete
                    uiwait(calibration_app.UIFigure);

                    % Get the updated ex back
                    app.ex = calibration_app.ex;

                    % Clean up
                    delete(calibration_app)
                catch ME
                    uialert(app.UIFigure, sprintf('Error with calibration software: %s', ME.message), 'Calibration Error');
                end
            end

            app.CalibrateButton.Text = 'Redo calibration';
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: InitializeButton
        function start_init(app, event)
            addpath(genpath("\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab"))

            % Check hydrophone amplifier setting
            answer = uiconfirm(app.UIFigure, ...
                'Has the hydrophone amplifier been set to 100 mV/Pa?', ...
                'Confirm Amplifier Setting', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);

            if strcmp(answer, 'No')
                return;
            end

            % Check if all required fields are filled
            if isempty(app.EditField_ID.Value) || isempty(app.DropDown_sex.Value) || ...
                    isnan(app.EditField_frequency.Value) || isempty(app.DropDown_response_feature.Value) || isnan(app.EditField_max_trials.Value)

                uialert(app.UIFigure, 'Make sure all fields are filled out prior to continuing.', 'Missing Fields');
                return;
            end

            % Initialize ex structure
            app.ex = setup_ex();
            app.ex = setup_hardware(app.ex);
            app.ex = setup_stimuli(app.ex);
            app.ex.info.animal.subject_ID = app.EditField_ID.Value;
            app.ex.info.animal.sex = app.DropDown_sex.Value;
            app.ex.info.animal.health = app.DropDown_health.Value;
            app.ex.info.stimulus.frequency_hz = app.EditField_frequency.Value;
            app.ex.info.stimulus.type = app.DropDown_tone_burst.Value;
            app.ex.info.adaptive.response_feature = app.DropDown_response_feature.Value;
            app.ex.info.adaptive.max_trials = app.EditField_max_trials.Value;
            app.ex.info.animal.filename = [app.ex.info.animal.species_name '_' ...
                num2str(app.ex.info.animal.subject_ID) '_' ...
                num2str(app.ex.info.stimulus.frequency_hz) '_Hz_' ...
                num2str(app.ex.info.stimulus.amplitude_spl) '_SPL_' ...
                app.ex.info.experiment.exp_date '_' ...
                app.ex.info.experiment.exp_time_start];

            app.InitializeButton.Text = 'Redo initialization';
            app.CalibrateButton.Enable = 'on';

        end

        % Button pushed function: HealthButton
        function HealthButtonPushed(app, event)
            app.ex.info.health.filename_root = strcat(app.ex.info.animal.species_name, '_', app.ex.info.animal.subject_ID);
            app.ex = check_health(app.ex, app);
            app.TempButton.Enable = 'on';
        end

        % Button pushed function: TempButton
        function TempButtonPushed(app, event)
            app.StartButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_hydrophone = uiaxes(app.UIFigure);
            title(app.UIAxes_hydrophone, 'Hydrophone')
            zlabel(app.UIAxes_hydrophone, 'Z')
            app.UIAxes_ch1 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch1, 'Channel 1')
            zlabel(app.UIAxes_ch1, 'Z')
            app.UIAxes_ch2 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch2, 'Channel 2')
            ylabel(app.UIAxes_ch2, 'Amplitude (mV)')
            zlabel(app.UIAxes_ch2, 'Z')
            app.UIAxes_ch3 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch3, 'Channel 3')
            zlabel(app.UIAxes_ch3, 'Z')
            app.UIAxes_ch4 = uiaxes(app.UIFigure);
            title(app.UIAxes_ch4, 'Channel 4')
            xlabel(app.UIAxes_ch4, 'Time (s)')
            zlabel(app.UIAxes_ch4, 'Z')
            app.UIAxes_boot = uiaxes(app.UIFigure);
            title(app.UIAxes_boot, 'Bootstrap Distribution')
            xlabel(app.UIAxes_boot, 'Difference')
            ylabel(app.UIAxes_boot, 'Frequency')
            zlabel(app.UIAxes_boot, 'Z')
            app.UIAxes_model = uiaxes(app.UIFigure);
            title(app.UIAxes_model, 'Model')
            xlabel(app.UIAxes_model, 'Stimulus Amplitude (dB SPL)')
            ylabel(app.UIAxes_model, '2f Amplitude (mV)')
            zlabel(app.UIAxes_model, 'Z')
            app.UIAxes_health = uiaxes(app.UIFigure);
            title(app.UIAxes_health, 'Health')
            xlabel(app.UIAxes_health, 'Iteration')
            ylabel(app.UIAxes_health, 'Response (mV)')
            zlabel(app.UIAxes_health, 'Z')
            app.UIAxes_temp = uiaxes(app.UIFigure);
            title(app.UIAxes_temp, 'Water Temp')
            xlabel(app.UIAxes_temp, 'Iteration')
            ylabel(app.UIAxes_temp, 'Temp')
            zlabel(app.UIAxes_temp, 'Z')
            app.UIAxes_diff_fft = uiaxes(app.UIFigure);
            title(app.UIAxes_diff_fft, 'Difference FFT')
            xlabel(app.UIAxes_diff_fft, 'Frequency (Hz)')
            ylabel(app.UIAxes_diff_fft, 'Amplitude (mV)')
            zlabel(app.UIAxes_diff_fft, 'Z')
            app.UIAxes_thresh_est = uiaxes(app.UIFigure);
            title(app.UIAxes_thresh_est, 'Threshold Estimate')
            xlabel(app.UIAxes_thresh_est, 'Iteration')
            ylabel(app.UIAxes_thresh_est, 'x0')
            zlabel(app.UIAxes_thresh_est, 'Z')
            app.UIAxes_slope_est = uiaxes(app.UIFigure);
            title(app.UIAxes_slope_est, 'Slope Estimate')
            xlabel(app.UIAxes_slope_est, 'Iteration')
            ylabel(app.UIAxes_slope_est, 'm')
            zlabel(app.UIAxes_slope_est, 'Z')
            app.UIAxes_monte = uiaxes(app.UIFigure);
            title(app.UIAxes_monte, 'Best Testing Amplitude Simulation')
            xlabel(app.UIAxes_monte, 'Sampled Level (dB SPL)')
            ylabel(app.UIAxes_monte, 'RMS Error (\muV)')
            zlabel(app.UIAxes_monte, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '__________________________';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '__________________________';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID #:';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex:';
            app.StimulusLabel = uilabel(app.UIFigure);
            app.StimulusLabel.Text = 'Stimulus';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ResponsefeatureLabel = uilabel(app.UIFigure);
            app.ResponsefeatureLabel.Text = 'Response feature:';
            app.MaximumtrialsLabel_2 = uilabel(app.UIFigure);
            app.MaximumtrialsLabel_2.Text = 'Maximum trials:';
            app.ExperimentLabel = uilabel(app.UIFigure);
            app.ExperimentLabel.Text = 'Experiment';
            app.EditField_ID = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ID.AllowEmpty = 'on';
            app.EditField_ID.Value = [];
            app.EditField_frequency = uieditfield(app.UIFigure, 'numeric');
            app.EditField_frequency.AllowEmpty = 'on';
            app.EditField_frequency.Value = [];
            app.EditField_max_trials = uieditfield(app.UIFigure, 'numeric');
            app.EditField_max_trials.Value = 300;
            app.logo = uiimage(app.UIFigure);
            app.logo.ImageSource = '\\wsl$\ubuntu\home\aoih\adapt_aep\src\matlab\adapt_aep_logo_trans.png';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @start_calibration, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start_exp, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DropDown_response_feature = uidropdown(app.UIFigure);
            app.DropDown_response_feature.Items = {'Double frequency'};
            app.DropDown_response_feature.Value = 'Double frequency';
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.AnalysisLabel = uilabel(app.UIFigure);
            app.AnalysisLabel.Text = 'Analysis';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @start_init, true);
            app.InitializeButton.Text = 'Initialize';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.DropDown_tone_burst = uidropdown(app.UIFigure);
            app.DropDown_tone_burst.Items = {'Tone burst'};
            app.DropDown_tone_burst.Value = 'Tone burst';
            app.CurrentamplitudeLabel = uilabel(app.UIFigure);
            app.CurrentamplitudeLabel.Text = 'Current amplitude: ';
            app.NumberoftrialspresentedLabel = uilabel(app.UIFigure);
            app.NumberoftrialspresentedLabel.Text = 'Number of trials presented:';
            app.TrialrejectionrateLabel = uilabel(app.UIFigure);
            app.TrialrejectionrateLabel.Text = 'Trial rejection rate:';
            app.Label_current_amp = uilabel(app.UIFigure);
            app.Label_current_amp.Text = 'X';
            app.Label_number_trials_presented = uilabel(app.UIFigure);
            app.Label_number_trials_presented.Text = 'X';
            app.GrandnumberoftrialspresentedLabel = uilabel(app.UIFigure);
            app.GrandnumberoftrialspresentedLabel.Text = 'Grand number of trials presented: ';
            app.Label_rejection_rate = uilabel(app.UIFigure);
            app.Label_rejection_rate.Text = 'X';
            app.Label_grand_total = uilabel(app.UIFigure);
            app.Label_grand_total.Text = 'X';
            app.peramplitudeLabel = uilabel(app.UIFigure);
            app.peramplitudeLabel.Text = 'per amplitude';
            app.TotaltimeelapsedLabel = uilabel(app.UIFigure);
            app.TotaltimeelapsedLabel.Text = 'Total time elapsed:';
            app.Label_time_elapsed = uilabel(app.UIFigure);
            app.Label_time_elapsed.Text = 'X';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DropDown_sex = uidropdown(app.UIFigure);
            app.DropDown_sex.Items = {'F', 'M'};
            app.DropDown_sex.Value = 'F';
            app.HealthButton = uibutton(app.UIFigure, 'push');
            app.HealthButton.ButtonPushedFcn = createCallbackFcn(app, @HealthButtonPushed, true);
            app.HealthButton.Enable = 'off';
            app.HealthButton.Text = 'Health';
            app.TempButton = uibutton(app.UIFigure, 'push');
            app.TempButton.ButtonPushedFcn = createCallbackFcn(app, @TempButtonPushed, true);
            app.TempButton.Enable = 'off';
            app.TempButton.Text = 'Temp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adapt_aep

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end